classdef fc_vehicle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SimulationTimeEditField        matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        MotorSpecTab                   matlab.ui.container.Tab
        FrictionCoefficientEditField   matlab.ui.control.NumericEditField
        FrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        DragCoefficientEditField       matlab.ui.control.NumericEditField
        DragCoefficientEditFieldLabel  matlab.ui.control.Label
        AirDensityEditField            matlab.ui.control.NumericEditField
        AirDensityEditFieldLabel       matlab.ui.control.Label
        GradientEditField              matlab.ui.control.NumericEditField
        GradientLabel                  matlab.ui.control.Label
        GearRatioEditField             matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel        matlab.ui.control.Label
        WheelRadiusEditField           matlab.ui.control.NumericEditField
        WheelRadiusEditFieldLabel      matlab.ui.control.Label
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassLabel                      matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        PerformanceTab                 matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        TimeTakenfor20to50EditField    matlab.ui.control.NumericEditField
        TimeTakenfor20to50EditFieldLabel  matlab.ui.control.Label
        TopSpeedEditField              matlab.ui.control.NumericEditField
        TopSpeedEditFieldLabel         matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        FCCharecteristicsandDriveCycleTab  matlab.ui.container.Tab
        Image3                         matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        EditDefaultValuesTab           matlab.ui.container.Tab
        BatterySpecificationsMaxcapacity3kWhLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Results1Tab                    matlab.ui.container.Tab
        ShowDriveCycleCheckBox         matlab.ui.control.CheckBox
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        Results2Tab                    matlab.ui.container.Tab
        RangeEditField                 matlab.ui.control.NumericEditField
        RangeEditFieldLabel            matlab.ui.control.Label
        CorrectedFuelEfficiencyEditField  matlab.ui.control.NumericEditField
        CorrectedFuelEfficiencyEditFieldLabel  matlab.ui.control.Label
        RawFuelEfficiencyEditField     matlab.ui.control.NumericEditField
        RawFuelEfficiencyEditFieldLabel  matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
%       The properties that can be accessed every where in the app:
%       Engine Spec and g

        W_Mot_Idx_RPM = [0 2000 4000 5000 6000 7000 8000 10000 12000]
        T_Mot_Max_Cnt_Idx_Nm = [130 130 130  130 110 95 80 65 50];
        Eta_Mot_M_2_E_per = 88 % Efficiency of the motor (Is it E_2_M ?)
        g = 9.87;

%         Part 2:
        WLTP_LR_kmph = load("WLTP_LR_kmph.mat").WLTP_LR_kmph;
        fcstack = load("my_fcstack.mat").my_fcstack;
        battery = load("my_battery.mat").my_battery;
        h2_LHV_kJpkg = 120000;
%       Label about battery default values,LHV

        property = {'Eta_DT_Whl_2_Mot_per';'Eta_Mot_M_2_E_per';
            'FCPS_ramp_up_rt_kWps';'FCPS_ramp_dwn_rt_kWps';'v_veh_regen_min_kmph';
            'eta_FCPS_BOP_per';'eta_dcdc_2_fc_per';'eta_FCPS_per';
            'P_aux_veh_kW';'h2_usable_kg'};
        default_value = [90;98;5;5;5;10;97;50;0.5;5];
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes,app.W_Mot_Idx_RPM,app.T_Mot_Max_Cnt_Idx_Nm, ...
                "r-sq");
            grid(app.UIAxes,'on');
            plot(app.UIAxes4,app.WLTP_LR_kmph(:,1),app.WLTP_LR_kmph(:,2));
            plot(app.UIAxes3,app.fcstack.I_idx_A,app.fcstack.V_idx_V);
            grid(app.UIAxes4,'on');
            grid(app.UIAxes3,'on');
            
            app.AirDensityEditField.Value = 1.17;
            app.DragCoefficientEditField.Value = 0.7;
            app.SimulationTimeEditField.Value = 120;

%         Table With Default Values:
           default_values_table = table(app.property,app.default_value,'VariableNames', ...
               {'Property';'Value'},'RowNames',app.property);
           app.UITable.Data = default_values_table;
           app.UITable.ColumnEditable = [false,true];
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed2(app, event)
%           Get the inputs from the inputs field
            T_Sim_Prf = app.SimulationTimeEditField.Value;
            M = app.MassEditField.Value;
            A = app.AreaEditField.Value;
            r_whl = app.WheelRadiusEditField.Value;
            GR = app.GearRatioEditField.Value;
            mu = app.FrictionCoefficientEditField.Value;
            Grd_prf = app.GradientEditField.Value;
            rho = app.AirDensityEditField.Value;
            Cd = app.DragCoefficientEditField.Value;

%           Use the external function get_Veh_Prf to compute required data
            [t_Prf_Idx_sec,v_Whl_Prf_mps,v_Whl_Top_Spd_kmph,t_20_2_50_kmph,F_Whl_Res_Prf_N,F_App_Whl_Prf_N] = get_Veh_Prf(T_Sim_Prf,M,A,r_whl,GR,app.W_Mot_Idx_RPM,app.T_Mot_Max_Cnt_Idx_Nm, ...
            mu,Grd_prf,rho,Cd);

%           Plot both the graphs
            plot(app.UIAxes2,t_Prf_Idx_sec,3.6*v_Whl_Prf_mps);
            grid(app.UIAxes2,'on');
            plot(app.UIAxes2_2,t_Prf_Idx_sec,F_Whl_Res_Prf_N,t_Prf_Idx_sec,F_App_Whl_Prf_N);
            hold on;
            grid(app.UIAxes2_2,'on');
            legend(app.UIAxes2_2,"F_Res","F_App");
            hold off;
            

%           Display the top-speed and t_20_2_50 in the resp fields
            app.TopSpeedEditField.Value = v_Whl_Top_Spd_kmph;
            app.TimeTakenfor20to50EditField.Value = t_20_2_50_kmph;

%             Part 2:
            car  = Vehicle (M,A,r_whl,GR);
            conditions = Conditions(Grd_prf,Cd,mu,rho);
            T = app.UITable.Data;

%         Calculate the net power demand form the motor:
%         Second variable is the net power demand, first includes ramping
             [P_FCPS_net_req_kW,P_mot_req_kW] = get_P_FCPS_net_req_kW(car, ...
                 conditions,app.WLTP_LR_kmph,T('Eta_DT_Whl_2_Mot_per',:).Value, ...
                 T('Eta_Mot_M_2_E_per',:).Value,T('FCPS_ramp_up_rt_kWps',:).Value, ...
                 T('FCPS_ramp_dwn_rt_kWps',:).Value, ...
                 T('v_veh_regen_min_kmph',:).Value);

%         Calculate power demand at dcdc converter
            [P_at_dcdc_kW,V_dcdc_out_V] = get_P_FCPS_tot_req_kW( ...
    P_FCPS_net_req_kW,T('eta_FCPS_BOP_per',:).Value,app.battery.V_norm_V);
        time = app.WLTP_LR_kmph(:,1);

%          Calculate power demand at the battery and its SOC   
            [P_at_battery_kW,SOC_battery_per] = show_battery(app.battery,...
    time,P_FCPS_net_req_kW,P_mot_req_kW, ...
    T('P_aux_veh_kW',:).Value);

%          Calculate the power demand at FC stack by dcdc
%          V_FC_Stack_V = V_dcdc_in_V
            [~,V_FCStack_V] = get_FCStack( ...
    P_at_dcdc_kW,T('eta_dcdc_2_fc_per',:).Value,app.fcstack);

%           Calculate the h2 consuption by fc stack
            [M_h2_tnk_kg] = h2_consuption(0,time, ...
    P_FCPS_net_req_kW,T('eta_FCPS_per',:).Value,app.h2_LHV_kJpkg);

%           calcualte efficiencies    
            SOC_bat_final_per = SOC_battery_per(length(SOC_battery_per));
            net_battery_usage_kWh = app.battery.max_cap_kWh*( ...
                SOC_bat_final_per-app.battery.SOC_init_per);

            M_h2_consumed = M_h2_tnk_kg(length(M_h2_tnk_kg));

            [FE_raw_kmpkg,FE_cor_kmpkg,range] = efficiency(app.WLTP_LR_kmph, ...
                M_h2_consumed,net_battery_usage_kWh,T('eta_FCPS_per',:).Value, ...
                T('h2_usable_kg',:).Value,app.h2_LHV_kJpkg);
            
%          Plot the results 
           if(app.ShowDriveCycleCheckBox.Value == 1)
               factor = (max(V_FCStack_V)-min(V_FCStack_V))/max(app.WLTP_LR_kmph(:,2));
               plot(app.UIAxes7,time,V_FCStack_V,time,V_dcdc_out_V,time, ...
                   app.WLTP_LR_kmph(:,2)*factor+(min(V_FCStack_V)));
                hold on;
           else
               plot(app.UIAxes7,time,V_FCStack_V,time,V_dcdc_out_V);
               hold on;
           end
           grid(app.UIAxes7,'minor');
           legend(app.UIAxes7,'DCDC Voltage IN','DCDC Voltage OUT');
           hold off;


           if(app.ShowDriveCycleCheckBox.Value == 1)
               factor = (max(P_mot_req_kW)-min(P_mot_req_kW))/max(app.WLTP_LR_kmph(:,2));
               plot(app.UIAxes8,time,P_FCPS_net_req_kW,time,P_at_battery_kW, ...
               time,P_mot_req_kW,time,app.WLTP_LR_kmph(:,2)*factor);
           hold on;
           else
               plot(app.UIAxes8,time,P_FCPS_net_req_kW,time,P_at_battery_kW, ...
               time,P_mot_req_kW);
           hold on;
           end
           grid(app.UIAxes8,'minor');
           legend(app.UIAxes8,'Power FCPC','Power Battery','Power Motor');
           hold off;

           plot(app.UIAxes6,time,SOC_battery_per);
           grid(app.UIAxes6,'on');
           plot(app.UIAxes5,time,M_h2_tnk_kg);
           grid(app.UIAxes5,'on');

%          display efficiencies
           app.RangeEditField.Value = range;
           app.RawFuelEfficiencyEditField.Value = FE_raw_kmpkg;
           app.CorrectedFuelEfficiencyEditField.Value = FE_cor_kmpkg;

           close(gcf);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed2, true);
            app.RefreshButton.Text = 'Refresh';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Hydrogen Fuel Cell Vehicle Component Sizing for Performance and Fuel Economy Optimization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorSpecTab = uitab(app.TabGroup);
            app.MotorSpecTab.Title = 'Motor Spec';
            app.UIAxes = uiaxes(app.MotorSpecTab);
            title(app.UIAxes, 'Motor Specification')
            xlabel(app.UIAxes, 'speed (rpm)')
            ylabel(app.UIAxes, 'Torque (Nm)')
            zlabel(app.UIAxes, 'Z')
            app.Image2 = uiimage(app.MotorSpecTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'traction_motor.jpg');
            app.MassLabel = uilabel(app.MotorSpecTab);
            app.MassLabel.Text = 'Mass    ';
            app.MassEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.MassEditField.ValueDisplayFormat = '%11.4g Kg';
            app.AreaEditFieldLabel = uilabel(app.MotorSpecTab);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.AreaEditField.ValueDisplayFormat = '%11.4g m^2';
            app.WheelRadiusEditFieldLabel = uilabel(app.MotorSpecTab);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius';
            app.WheelRadiusEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.WheelRadiusEditField.ValueDisplayFormat = '%11.4g m';
            app.GearRatioEditFieldLabel = uilabel(app.MotorSpecTab);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.GradientLabel = uilabel(app.MotorSpecTab);
            app.GradientLabel.Text = 'Gradient %';
            app.GradientEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.AirDensityEditFieldLabel = uilabel(app.MotorSpecTab);
            app.AirDensityEditFieldLabel.Text = 'Air Density';
            app.AirDensityEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.AirDensityEditField.ValueDisplayFormat = '%11.4g kg/m3';
            app.DragCoefficientEditFieldLabel = uilabel(app.MotorSpecTab);
            app.DragCoefficientEditFieldLabel.Text = 'Drag Coefficient';
            app.DragCoefficientEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.FrictionCoefficientEditFieldLabel = uilabel(app.MotorSpecTab);
            app.FrictionCoefficientEditFieldLabel.Text = 'Friction Coefficient';
            app.FrictionCoefficientEditField = uieditfield(app.MotorSpecTab, 'numeric');
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.UIAxes2 = uiaxes(app.PerformanceTab);
            title(app.UIAxes2, 'Vehicle Performance')
            xlabel(app.UIAxes2, 'time(sec)')
            ylabel(app.UIAxes2, 'Speed (kmph)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.PerformanceTab);
            title(app.UIAxes2_2, 'Forces on Tyres')
            xlabel(app.UIAxes2_2, 'time(sec)')
            ylabel(app.UIAxes2_2, 'Force (N)')
            zlabel(app.UIAxes2_2, 'Z')
            app.TopSpeedEditFieldLabel = uilabel(app.PerformanceTab);
            app.TopSpeedEditFieldLabel.Text = 'Top Speed';
            app.TopSpeedEditField = uieditfield(app.PerformanceTab, 'numeric');
            app.TopSpeedEditField.ValueDisplayFormat = '%11.4g km/hr';
            app.TopSpeedEditField.Editable = 'off';
            app.TimeTakenfor20to50EditFieldLabel = uilabel(app.PerformanceTab);
            app.TimeTakenfor20to50EditFieldLabel.Text = 'Time Taken for 20 to 50';
            app.TimeTakenfor20to50EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.TimeTakenfor20to50EditField.ValueDisplayFormat = '%11.4g sec';
            app.TimeTakenfor20to50EditField.Editable = 'off';
            app.Label_2 = uilabel(app.PerformanceTab);
            app.Label_2.Text = 'Note that if Time taken from 20kmph to 50kmph is -1 then 50kmph is never reached';
            app.FCCharecteristicsandDriveCycleTab = uitab(app.TabGroup);
            app.FCCharecteristicsandDriveCycleTab.Title = 'FC Charecteristics and Drive Cycle';
            app.UIAxes3 = uiaxes(app.FCCharecteristicsandDriveCycleTab);
            title(app.UIAxes3, 'FC Stack V vs I curve')
            xlabel(app.UIAxes3, 'Current (A)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.FCCharecteristicsandDriveCycleTab);
            title(app.UIAxes4, 'WLTP LR Drive Cycle')
            xlabel(app.UIAxes4, 'Time (Sec)')
            ylabel(app.UIAxes4, 'Speed (km/hr)')
            zlabel(app.UIAxes4, 'Z')
            app.Image3_2 = uiimage(app.FCCharecteristicsandDriveCycleTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'drive_cycle_image.jpg');
            app.Image3 = uiimage(app.FCCharecteristicsandDriveCycleTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'fc_stack.jpg');
            app.EditDefaultValuesTab = uitab(app.TabGroup);
            app.EditDefaultValuesTab.Title = 'Edit Default Values';
            app.UITable = uitable(app.EditDefaultValuesTab);
            app.UITable.ColumnName = {'Property'; 'Property Value'};
            app.UITable.RowName = {};
            app.BatterySpecificationsMaxcapacity3kWhLabel = uilabel(app.EditDefaultValuesTab);
            app.BatterySpecificationsMaxcapacity3kWhLabel.Text = 'Battery Specifications:  Max. capacity : 3 kWh    Voltage : 420V    initial SOC : 70%';
            app.Results1Tab = uitab(app.TabGroup);
            app.Results1Tab.Title = 'Results-1';
            app.UIAxes7 = uiaxes(app.Results1Tab);
            title(app.UIAxes7, 'Voltage at DCDC Inlet and Outlet')
            xlabel(app.UIAxes7, 'time (sec)')
            ylabel(app.UIAxes7, 'Voltage (V)')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.Results1Tab);
            title(app.UIAxes8, 'FC Power Train Power Requirements')
            xlabel(app.UIAxes8, 'time (sec)')
            ylabel(app.UIAxes8, 'Volage (V)')
            zlabel(app.UIAxes8, 'Z')
            app.ShowDriveCycleCheckBox = uicheckbox(app.Results1Tab);
            app.ShowDriveCycleCheckBox.Text = 'Show Drive Cycle';
            app.Results2Tab = uitab(app.TabGroup);
            app.Results2Tab.Title = 'Results-2';
            app.UIAxes5 = uiaxes(app.Results2Tab);
            title(app.UIAxes5, 'H2 Consumption')
            xlabel(app.UIAxes5, 'Time (sec)')
            ylabel(app.UIAxes5, 'H2 consumed (kg)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Results2Tab);
            title(app.UIAxes6, 'Battery SOC Variation')
            xlabel(app.UIAxes6, 'time (sec)')
            ylabel(app.UIAxes6, 'SOC (%)')
            zlabel(app.UIAxes6, 'Z')
            app.RawFuelEfficiencyEditFieldLabel = uilabel(app.Results2Tab);
            app.RawFuelEfficiencyEditFieldLabel.Text = 'Raw Fuel Efficiency';
            app.RawFuelEfficiencyEditField = uieditfield(app.Results2Tab, 'numeric');
            app.RawFuelEfficiencyEditField.ValueDisplayFormat = '%11.4g km/kg';
            app.CorrectedFuelEfficiencyEditFieldLabel = uilabel(app.Results2Tab);
            app.CorrectedFuelEfficiencyEditFieldLabel.Text = 'Corrected Fuel Efficiency';
            app.CorrectedFuelEfficiencyEditField = uieditfield(app.Results2Tab, 'numeric');
            app.CorrectedFuelEfficiencyEditField.ValueDisplayFormat = '%11.4g km/kg';
            app.RangeEditFieldLabel = uilabel(app.Results2Tab);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.Results2Tab, 'numeric');
            app.RangeEditField.ValueDisplayFormat = '%11.4g km';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'car_on_road.jpg');
            app.SimulationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimeEditField.UpperLimitInclusive = 'off';
            app.SimulationTimeEditField.Limits = [0 Inf];
            app.SimulationTimeEditField.ValueDisplayFormat = '%11.4g sec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fc_vehicle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end